classdef CONVET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TuvozhadichoTextArea       matlab.ui.control.TextArea
        TuvozhadichoTextAreaLabel  matlab.ui.control.Label
        ConviertetuvozenTextoButtonGroup  matlab.ui.container.ButtonGroup
        EliminarButton             matlab.ui.control.ToggleButton
        ContinuarButton            matlab.ui.control.ToggleButton
        GrabarButton               matlab.ui.control.ToggleButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: ConviertetuvozenTextoButtonGroup
        function ConviertetuvozenTextoButtonGroupButtonDown(app, event)
            if app.GrabarButton.Value
                % Inicia la grabación
                disp('Comenzando a grabar...');
                % Configura el objeto grabador de audio
                app.recorderObj = audiorecorder;
                % Inicia la grabación
                record(app.recorderObj);
            else
                % Detiene la grabación
                disp('Deteniendo la grabación...');
                % Detiene la grabación
                stop(app.recorderObj);
                % Obtiene los datos grabados
                app.audioData = getaudiodata(app.recorderObj);
                % Reproduce los datos grabados (puedes omitir esto si no es necesario)
                sound(app.audioData, app.recorderObj.SampleRate);
                % Guarda los datos grabados en un archivo (opcional)
                audiowrite('grabacion.wav', app.audioData, app.recorderObj.SampleRate);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voz')
            app.ConviertetuvozenTextoButtonGroup = uibuttongroup(app.UIFigure);
            app.ConviertetuvozenTextoButtonGroup.Title = 'Convierte tu voz en Texto';
            app.ConviertetuvozenTextoButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ConviertetuvozenTextoButtonGroupButtonDown, true);
            app.GrabarButton = uitogglebutton(app.ConviertetuvozenTextoButtonGroup);
            app.GrabarButton.Text = 'Grabar';
            app.GrabarButton.Value = true;
            app.ContinuarButton = uitogglebutton(app.ConviertetuvozenTextoButtonGroup);
            app.ContinuarButton.Text = 'Continuar';
            app.EliminarButton = uitogglebutton(app.ConviertetuvozenTextoButtonGroup);
            app.EliminarButton.Text = 'Eliminar';
            app.TuvozhadichoTextAreaLabel = uilabel(app.UIFigure);
            app.TuvozhadichoTextAreaLabel.Text = 'Tu voz ha dicho:';
            app.TuvozhadichoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CONVET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end